classdef Fuse_withTabs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        ResultsLabel        matlab.ui.control.Label
        MACHINELabel        matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        ModelDropDown       matlab.ui.control.DropDown
        ModelDropDownLabel  matlab.ui.control.Label
        Tab2                matlab.ui.container.Tab
        DropDown2           matlab.ui.control.DropDown
        DropDown2Label      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call the method to read Excel data
        [dropdownOptions, columnExists] = app.readExcelData();
        
        if columnExists
            % Update the dropdown component's items
            app.ModelDropDown.Items = dropdownOptions;
        else
            % Handle the case where the 'Model' column does not exist
            disp('Column "Model" does not exist in the specified sheet.');
        end
        end

%%%%%%


function [dropdownOptions, columnExists] = readExcelData(~)
% Define the path to your Excel file
        excelFilePath = 'C:\Users\BlossomFernandez\GB AUTO GROUP PTY LIMITED\DVT - Document\Projects\Simulink & MATLAB\Projects\APP_Fuse\Fuse_GUI.xlsx';
        
% Define the sheet name
        sheetName = 'MachinesOnSite';
        
% Read the header from the second row
        headerData = readcell(excelFilePath, 'Sheet', sheetName, 'Range', 'A2:X2');
        
% Read the actual data starting from the third row
        data = readtable(excelFilePath, 'Sheet', sheetName, 'Range', 'A2:X1000'); % Adjust range as needed
        
% Clean headers by removing line breaks and extra whitespace
cleanedHeaderData = regexprep(headerData, '[\n\r]+', '');  % Remove line breaks
cleanedHeaderData = strtrim(cleanedHeaderData);  % Trim leading/trailing spaces

% Assign cleaned column names to the table
data.Properties.VariableNames = cleanedHeaderData;
        
% Check if the 'Model' column exists
        columnExists = ismember('Model', data.Properties.VariableNames);
        
% to convert dropdownOptions to a cell array before it has been defined. To ensure that the readExcelData function works correctly, you need to handle the case where the 'Model' column exists and where it does not, and you must define dropdownOptions before trying to convert it.
        if columnExists
            dropdownOptions = data.Model; % Access the 'Model' column
            
% Remove empty entries
            dropdownOptions = dropdownOptions(~cellfun('isempty', dropdownOptions));
% Convert to cell array if needed
            if ~iscell(dropdownOptions)
                dropdownOptions = cellstr(dropdownOptions);
            end
        else
            dropdownOptions = {};
        end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
           %% TO DISPLAY MANUFACTURER AND CRANKING CURRENT %%
 % Model name
    selectedModel = app.ModelDropDown.Value;

    %% Model column data
    excelFilePath = 'C:\Users\BlossomFernandez\GB AUTO GROUP PTY LIMITED\DVT - Document\Projects\Simulink & MATLAB\Projects\APP_Fuse\Fuse_GUI.xlsx';

    %% Define the sheet name
    % MachineOnSite Sheet
    sheetNameMachinesOnSite = 'MachinesOnSite';
    % Fuse Library Sheet
    sheetNameFuseLibrary    = 'Fuse_Library';

    %% Read the header from the second row
    % MachineOnSite Sheet
    headerDataMachinesOnSite = readcell(excelFilePath, 'Sheet', sheetNameMachinesOnSite, 'Range', 'A2:X2');
    % Fuse Library Sheet
    headerDataFuseLibrary    = readcell(excelFilePath, 'Sheet', sheetNameFuseLibrary, 'Range', 'A1:K1');

    %% Read the actual data starting from the third row
    % MachineOnSite Sheet
    dataMachinesOnSite = readtable(excelFilePath, 'Sheet', sheetNameMachinesOnSite, 'Range', 'A3:X1000'); % Adjust range as needed
    % Fuse Library Sheet
    dataFuseLibrary    = readtable(excelFilePath, 'Sheet', sheetNameFuseLibrary, 'Range', 'A2:K20'); % Adjust range as needed

    %% Clean headers by removing line breaks and extra whitespace
    % MachineOnSite Sheet
    cleanedHeaderDataMachinesOnSite = regexprep(headerDataMachinesOnSite, '[\n\r]+', '');  % Remove line breaks
    cleanedHeaderDataMachinesOnSite = strtrim(cleanedHeaderDataMachinesOnSite);  % Trim leading/trailing spaces  
    % Fuse Library Sheet
    cleanedHeaderDataFuseLibrary = regexprep(headerDataFuseLibrary, '[\n\r]+', '');  % Remove line breaks
    cleanedHeaderDataFuseLibrary = strtrim(cleanedHeaderDataFuseLibrary);  % Trim leading/trailing spaces  

    %% Assign cleaned column names to the table
    % MachineOnSite Sheet
    dataMachinesOnSite.Properties.VariableNames = cleanedHeaderDataMachinesOnSite;
    % Fuse Library Sheet
    dataFuseLibrary.Properties.VariableNames    = cleanedHeaderDataFuseLibrary;   

    %% Access the Column  
    % MachineOnSite Sheet
    modelColumn = dataMachinesOnSite.Model;

    % Fuse Library Sheet
    FuseRatingColumn    = dataFuseLibrary.('Current rating (A)') ; 
    ThermalEnergyColumn = dataFuseLibrary.('I2t (A2s)');
    GBPartNumberColumn  = dataFuseLibrary.('GB PART #');


    %% Perform index matching to find the selected model
    rowIndexMachinesOnSite = find(ismember(modelColumn, selectedModel), 1);
    

    %% Initialize Values
    CrankingAmpsHighorLow = 'Data Unavailable'; % Default value
    
    if ~isempty(rowIndexMachinesOnSite)
        %% Assign Column Names from data table
        StarterMotorCrankingCurrentColumnName = 'Starter Motor Peak Current (A)';
        CrankingTimeColumnName                = 'Cranking time (s)';
        CableSizeColumnName                   = 'Cable Size(mm2)';
        AlternatorContinuousCurrentColumnName = 'Alternator Continuous Current (A)';
        CableContinuousCurrentColumnName      = 'Continuous Current (A)';
        

        %% Assign Data
        FusingSafetyCurrentFactor = 125/100;
        KFactorCopper             = 143;

        %% Extract the values in the respective column
        StarterMotorCrankingCurrentValue = dataMachinesOnSite{rowIndexMachinesOnSite, StarterMotorCrankingCurrentColumnName}; % Cranking current at 1000A 
        CrankingTimeValue                = dataMachinesOnSite{rowIndexMachinesOnSite, CrankingTimeColumnName}; % Cranking Time
        CableSizeValue                   = dataMachinesOnSite{rowIndexMachinesOnSite, CableSizeColumnName}; % Cable Size
        AlternatorContinuousCurrentValue = dataMachinesOnSite{rowIndexMachinesOnSite, AlternatorContinuousCurrentColumnName}; % Alternator Continuous Current
        CableContinuousCurrentValue      = dataMachinesOnSite{rowIndexMachinesOnSite, CableContinuousCurrentColumnName}; % Cable Continuous Current


        %% Apply the function to convert values
        StarterMotorCrankingCurrentValue = app.convertValue(StarterMotorCrankingCurrentValue);
        CrankingTimeValue                = app.convertValue(CrankingTimeValue);
        CableSizeValue                   = app.convertValue(CableSizeValue);
        AlternatorContinuousCurrentValue = app.convertValue(AlternatorContinuousCurrentValue);
        CableContinuousCurrentValue      = app.convertValue(CableContinuousCurrentValue);
        GBPartNumberColumn               = app.convertValue(GBPartNumberColumn);

        %% Debugging: Print the class of the converted value
        % disp(['Class of StarterMotorCrankingCurrentValue: ', class(StarterMotorCrankingCurrentValue)]);
        % disp(['Class of CrankingTimeValue: ', class(CrankingTimeValue)]);
        % disp(['Class of CableSizeValue: ', class(CableSizeValue)]);
        % disp(['Class of AlternatorContinuousCurrentValue: ', class(AlternatorContinuousCurrentValue)]);
        % disp(['Class of CableContinuousCurrentValue: ', class(CableContinuousCurrentValue)]);
        
        
        %% Check if parameter is 'Data Unavailable' or another string like 'TBC'
        
        %Is the cranking current less than the max (1000A) value? 

        if ischar(StarterMotorCrankingCurrentValue) || isstring(StarterMotorCrankingCurrentValue)
            % Handle cases where the value is 'Data Unavailable', 'TBC', etc.
            if strcmp(StarterMotorCrankingCurrentValue, 'Data Unavailable') || strcmp(StarterMotorCrankingCurrentValue, 'TBC')
                CrankingAmpsHighorLow = 'Data Unavailable';
            end
        elseif isnumeric(StarterMotorCrankingCurrentValue)

            % If it's numeric, compare it
            if StarterMotorCrankingCurrentValue <= 1000
                CrankingAmpsHighorLow = 'YES';  % Cranking current is <= 1000A
            else
                CrankingAmpsHighorLow = 'NO, Cranking amps over the limit, replace the starter motor';  % Cranking current is greater than 1000A
            end
        else
            % Handle unexpected data types
            CrankingAmpsHighorLow = 'Invalid Data';
        end
    end

      %% Check if the parameter is 'Data Unavailable' or another string like 'TBC'

      %Can the cable handle the in-rush current demand? 

        if ischar(CrankingTimeValue) || isstring(CrankingTimeValue) || ischar(CableSizeValue) || isstring(CableSizeValue) 
            % Handle cases where the value is 'Data Unavailable', 'TBC', etc.
            if strcmp(CrankingTimeValue, 'Data Unavailable') || strcmp(CrankingTimeValue, 'TBC') || strcmp(CableSizeValue, 'Data Unavailable') || strcmp(CableSizeValue, 'TBC')
               
                CablePeakCurrent = 'Data Unavailable';
            end

        elseif isnumeric(CrankingTimeValue) && isnumeric(CableSizeValue) 

            % If it's numeric, compare it
            if CrankingTimeValue <= 5
                CablePeakCurrent = ((KFactorCopper*CableSizeValue)/ sqrt (CrankingTimeValue)) ;
            else
                CablePeakCurrent = ['Cables unable handle peak current beyond 5s, ' ...
                    'investigate electrical component that needs servicing'];  
            end
        else
            % Handle unexpected data types
                CablePeakCurrent = 'Invalid Data';
        end
    
   
    %% Can the cable handle the in-rush current demand?  

    if ischar(StarterMotorCrankingCurrentValue) || isstring(StarterMotorCrankingCurrentValue) || ischar(CablePeakCurrent) || isstring(CablePeakCurrent)
            % Handle cases where the value is 'Data Unavailable', 'TBC', etc.
            if strcmp(StarterMotorCrankingCurrentValue, 'Data Unavailable') || strcmp(StarterMotorCrankingCurrentValue, 'TBC') || strcmp(CablePeakCurrent, 'Data Unavailable') || strcmp(CablePeakCurrent, 'TBC')
               
                CablePeakHandlingCapability = 'Data Unavailable';
            end

        elseif isnumeric(StarterMotorCrankingCurrentValue) && isnumeric(CablePeakCurrent) 

            % If it's numeric, compare it
            if StarterMotorCrankingCurrentValue <= CablePeakCurrent
                CablePeakHandlingCapability = 'YES';
            else
                CablePeakHandlingCapability = 'NO, Change the cable to one size up'; 
            end
        else
            % Handle unexpected data types
                CablePeakHandlingCapability = 'Invalid Data';
    end

   %% Can the cable handle the continuous current demand?

   if ischar(AlternatorContinuousCurrentValue) || isstring(AlternatorContinuousCurrentValue) || ischar(CableContinuousCurrentValue) || isstring(CableContinuousCurrentValue)
            % Handle cases where the value is 'Data Unavailable', 'TBC', etc.
            if strcmp(AlternatorContinuousCurrentValue, 'Data Unavailable') || strcmp(AlternatorContinuousCurrentValue, 'TBC') || strcmp(CableContinuousCurrentValue, 'Data Unavailable') || strcmp(CableContinuousCurrentValue, 'TBC')
               
                CableContiHandlingCapability = 'Data Unavailable';
            end

        elseif isnumeric(AlternatorContinuousCurrentValue) && isnumeric(CableContinuousCurrentValue) 

            % If it's numeric, compare it
            if AlternatorContinuousCurrentValue <= CableContinuousCurrentValue
                CableContiHandlingCapability = 'YES';
            else
                CableContiHandlingCapability = 'NO, Change the cable to one size up'; 
            end
        else
            % Handle unexpected data types
                CableContiHandlingCapability = 'Invalid Data';
    end
%% Fuse Current Rating (A) calculation

if strcmp(CableContiHandlingCapability, 'YES')
    FuseCurrentRatingCalculation = FusingSafetyCurrentFactor * CableContinuousCurrentValue;
else
    FuseCurrentRatingCalculation = 'Data Unavailable';

end

%% Closest Fuse Match Calculation

% Check if FuseCurrentRating is available and numeric
if isnumeric(FuseCurrentRatingCalculation)
    % Calculate the absolute difference between the Fuse options and the current rating
    diff = abs(FuseRatingColumn - FuseCurrentRatingCalculation);

    % Find the index of the minimum difference
    [~, idx] = min(diff);  %The first output is the minimum value itself. The second output is the index of that minimum value in the array.

    % If an index is found, get the closest fuse match
    if ~isempty(idx)
        FuseClosestMatch = FuseRatingColumn(idx);
    else
        FuseClosestMatch = 'Data Unavailable';
    end
else
    FuseClosestMatch = 'Data Unavailable';
end

%% Fuse Thermal Energy Calculation

% Check if FuseCurrentRating is available and numeric
if isnumeric(StarterMotorCrankingCurrentValue) && isnumeric(CrankingTimeValue) && strcmp(CrankingAmpsHighorLow, 'YES')
    % Calculate the absolute difference between the Fuse options and the current rating
   RequiredThermalEnergy = (StarterMotorCrankingCurrentValue)^2 * CrankingTimeValue;
    else
        RequiredThermalEnergy = 'Data Unavailable';
end

%% Thermal Energy Match Calculation

if isnumeric(RequiredThermalEnergy) && isnumeric(FuseClosestMatch)
    % Extract Thermal Energy Column from the table
   
    % Perform logical indexing to filter the values
    validRows = (FuseRatingColumn == FuseClosestMatch) & (ThermalEnergyColumn > RequiredThermalEnergy);
    
    % Check if there are valid matches
    matchingThermalEnergy = ThermalEnergyColumn(validRows);

    if ~isempty(matchingThermalEnergy)
        % Return the first match
        ThermalEnergyMatch = matchingThermalEnergy(1);
    else
        % No valid match found
        ThermalEnergyMatch = 'NO MATCH';
    end
else
    % If data is not available
    ThermalEnergyMatch = 'Data Unavailable';
end
%% Match GB Part #

% Assume the columns are already loaded into variables:
% GBPartNumberColumn, ThermalEnergyColumn, FuseRatingColumn, ThermalEnergyMatch, FuseClosestMatch

% Find rows where both conditions are true
matchingRows = (ThermalEnergyColumn == ThermalEnergyMatch) & (FuseRatingColumn == FuseClosestMatch);

% Find the first matching row
rowIndex = find(matchingRows, 1);

% Retrieve the corresponding value from GBPartNumberColumn
if ~isempty(rowIndex)
    GBPartNumberValue = GBPartNumberColumn(rowIndex);
else
    GBPartNumberValue = 'Data Unavailable';  % Handle the case when no match is found
end

% Display the result
disp(['GBPartNumberValue: ', num2str(GBPartNumberValue)]);


    %% Debugging:
    % disp(['FuseClosestMatch: ', num2str(FuseClosestMatch)]);
    % disp(['RequiredThermalEnergy: ', num2str(RequiredThermalEnergy)]);
    % disp(['ThermalEnergyMatch: ', num2str(ThermalEnergyMatch)]); 
    % disp(['GBPartNumber: ',char(GBPartNumberValue)]); 
    %disp(['Class of FuseCurrentRatingValue: ', class(FuseCurrentRatingValue)]); 
    % disp(['StarterMotorCrankingCurrentValue: ', num2str(StarterMotorCrankingCurrentValue)]);
    % disp(['CrankingTimeValue: ', num2str(CrankingTimeValue)]);
    % disp(['CableSizeValue: ', num2str(CableSizeValue)]);
    % disp(['AlternatorContinuousCurrentValue: ', num2str(AlternatorContinuousCurrentValue)]);
    % disp(['CableContinuousCurrentValue: ', num2str(CableContinuousCurrentValue)]);
    % disp(['Is cranking current <= 1000A: ', num2str(CrankingAmpsHighorLow)]);
    % disp(['Peak/short circuit current carrying capability (A) ', num2str(CablePeakCurrent)]);
    % disp(['CablePeakHandlingCapability ', num2str(CablePeakHandlingCapability)]);
    % disp(['CableContiHandlingCapability ', num2str(CablePeakHandlingCapability)]);
    % disp(['FuseCurrentRating ', num2str(FuseCurrentRatingCalculation)]);
    % disp(['FuseClosestMatch ', num2str(FuseClosestMatch)]);
    % disp(['RequiredThermalEnergy ', num2str(RequiredThermalEnergy)]);

    
%% Set UITable Data
tableData = {
    'Is the Starter Motor Cranking amps within the limit?', CrankingAmpsHighorLow;
    'Can the cable handle the in-rush current demand?', CablePeakHandlingCapability;
    'Can the cable handle the continuous current demand?', CableContiHandlingCapability;
    'Closest match for the fusing rating (A)', FuseClosestMatch;
    'GB Part #', char(GBPartNumberValue);  % Convert non-text values to char
};

% Set the table data in the UITable
app.UITable.Data = tableData;

%% Define a function to apply styles based on cell values
function applyStyles(app, row, col, fontColor, fontWeight)
    style = uistyle;
    style.FontColor = fontColor;     % Set font color
    style.FontWeight = fontWeight;   % Set font weight
    
    % Apply the style to the specific cell
    addStyle(app.UITable, style, 'cell', [row, col]);
end

%% Define a function to apply alignment
function applyAlignment(app, row, col, hAlign)
    alignStyle = uistyle;
    alignStyle.HorizontalAlignment = hAlign; % Set horizontal alignment
    
    % Apply the alignment style to the specific cell
    addStyle(app.UITable, alignStyle, 'cell', [row, col]);
end

%% Iterate through each column and apply styles based on content
for col = 1:size(tableData, 2)
    
    % First Row - CrankingAmpsHighorLow
    if isstring(tableData{1, col}) || ischar(tableData{1, col})
        switch tableData{1, col}
            case 'Data Unavailable'
                applyStyles(app, 1, col, 'yellow', 'bold');
            case 'YES'
                applyStyles(app, 1, col, 'green', 'bold');
            case 'NO, Cranking amps over the limit, replace the starter motor'
                applyStyles(app, 1, col, 'red', 'bold');
            case 'Invalid Data'
                applyStyles(app, 1, col, 'gray', 'bold');
            otherwise
                applyStyles(app, 1, col, 'black', 'normal');
        end
    elseif isnumeric(tableData{1, col})
        applyStyles(app, 1, col, 'black', 'normal'); % Numeric values should be black
    end
    applyAlignment(app, 1, col, 'left'); % Left-align

    % Second Row - CablePeakHandlingCapability
    if isstring(tableData{2, col}) || ischar(tableData{2, col})
        switch tableData{2, col}
            case 'Data Unavailable'
                applyStyles(app, 2, col, 'yellow', 'bold');
            case 'YES'
                applyStyles(app, 2, col, 'green', 'bold');
            case 'NO, Change the cable to one size up'
                applyStyles(app, 2, col, 'red', 'bold');
            case 'Invalid Data'
                applyStyles(app, 2, col, 'gray', 'bold');
            otherwise
                applyStyles(app, 2, col, 'black', 'normal');
        end
    elseif isnumeric(tableData{2, col})
        applyStyles(app, 2, col, 'black', 'normal'); % Numeric values should be black
    end
    applyAlignment(app, 2, col, 'left'); % Left-align

    % Third Row - CableContiHandlingCapability
    if isstring(tableData{3, col}) || ischar(tableData{3, col})
        switch tableData{3, col}
            case 'Data Unavailable'
                applyStyles(app, 3, col, 'yellow', 'bold');
            case 'YES'
                applyStyles(app, 3, col, 'green', 'bold');
            case 'NO, Change the cable to one size up'
                applyStyles(app, 3, col, 'red', 'bold');
            case 'Invalid Data'
                applyStyles(app, 3, col, 'gray', 'bold');
            otherwise
                applyStyles(app, 3, col, 'black', 'normal');
        end
    elseif isnumeric(tableData{3, col})
        applyStyles(app, 3, col, 'black', 'normal'); % Numeric values should be black
    end
    applyAlignment(app, 3, col, 'left'); % Left-align

    % Fourth Row - FuseClosestMatch
    if isstring(tableData{4, col}) || ischar(tableData{4, col})
        switch tableData{4, col}
            case 'Data Unavailable'
                applyStyles(app, 4, col, 'yellow', 'bold');
            case 'YES'
                applyStyles(app, 4, col, 'green', 'bold');
            case 'NO'
                applyStyles(app, 4, col, 'red', 'bold');
            case 'Invalid Data'
                applyStyles(app, 4, col, 'gray', 'bold');
            otherwise
                applyStyles(app, 4, col, 'black', 'normal');
        end
    elseif isnumeric(tableData{4, col})
        applyStyles(app, 4, col, 'black', 'normal'); % Numeric values should be black
    end
    applyAlignment(app, 4, col, 'left'); % Left-align

    % Fifth Row - GBPartNumberValue
    if isstring(tableData{5, col}) || ischar(tableData{5, col})
        switch tableData{5, col}
            case 'Data Unavailable'
                applyStyles(app, 5, col, 'yellow', 'bold');
            case 'YES'
                applyStyles(app, 5, col, 'green', 'bold');
            case 'NO'
                applyStyles(app, 5, col, 'red', 'bold');
            case 'Invalid Data'
                applyStyles(app, 5, col, 'gray', 'bold');
            otherwise
                applyStyles(app, 5, col, 'black', 'normal');
        end
    elseif isnumeric(tableData{5, col})
        applyStyles(app, 5, col, 'black', 'normal'); % Numeric values should be black
    end
    applyAlignment(app, 5, col, 'left'); % Left-align

end


        end
        %% Function to check type and convert values
function output = convertValue(~, val)
    if iscell(val)  % If it's a cell array
        val = val{1};  % Extract the first element
    end

    % Handle different data types
    if isnumeric(val)
        output = val;  % If numeric, leave it as is
    elseif ischar(val) || isstring(val)
        output = str2double(val);  % Convert char or string to numeric if possible
        if isnan(output)
            output = string(val);  % If conversion to numeric fails, convert to string
        end
    else
        output = val;  % If some other type, leave it unchanged (or handle as needed)
    end


        end

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ModelDropDownLabel = uilabel(app.Tab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.Tab);
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Item'; 'Value'};
            app.UITable.RowName = {};
            app.MACHINELabel = uilabel(app.Tab);
            app.MACHINELabel.Text = 'MACHINE';
            app.ResultsLabel = uilabel(app.Tab);
            app.ResultsLabel.Text = 'Results';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.DropDown2Label = uilabel(app.Tab2);
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.Tab2);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fuse_withTabs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end